classdef PIA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TerminalesdebajaencortocircuitoLabel  matlab.ui.control.Label
        TerminalesdebajaencortocircuitoLabel_2  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        WattsLabel                      matlab.ui.control.Label
        VoltsLabel                      matlab.ui.control.Label
        AmperesLabel                    matlab.ui.control.Label
        KVALabel                        matlab.ui.control.Label
        VoltsLabel_2                    matlab.ui.control.Label
        VoltsLabel_3                    matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        PotenciadeltransformadorEditFieldLabel  matlab.ui.control.Label
        PotenciadeltransformadorEditField  matlab.ui.control.NumericEditField
        VoltajedealtaEditFieldLabel     matlab.ui.control.Label
        VoltajedealtaEditField          matlab.ui.control.NumericEditField
        VoltajedebajaEditFieldLabel     matlab.ui.control.Label
        VoltajedebajaEditField          matlab.ui.control.NumericEditField
        TemperaturadepruebaEditFieldLabel  matlab.ui.control.Label
        TemperaturadepruebaEditField    matlab.ui.control.NumericEditField
        PccEditFieldLabel               matlab.ui.control.Label
        PccEditField                    matlab.ui.control.NumericEditField
        PoEditFieldLabel                matlab.ui.control.Label
        PoEditField                     matlab.ui.control.NumericEditField
        VccEditFieldLabel               matlab.ui.control.Label
        VccEditField                    matlab.ui.control.NumericEditField
        VxEditFieldLabel                matlab.ui.control.Label
        VxEditField                     matlab.ui.control.NumericEditField
        IccEditFieldLabel               matlab.ui.control.Label
        IccEditField                    matlab.ui.control.NumericEditField
        IexcEditFieldLabel              matlab.ui.control.Label
        IexcEditField                   matlab.ui.control.NumericEditField
        CalcularButton                  matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_10                    matlab.ui.control.NumericEditField
        autofillButton                  matlab.ui.control.Button
        TemperaturafinalEditFieldLabel  matlab.ui.control.Label
        TemperaturafinalEditField       matlab.ui.control.NumericEditField
        CLabel_2                        matlab.ui.control.Label
        RHEditFieldLabel                matlab.ui.control.Label
        RHEditField                     matlab.ui.control.NumericEditField
        RXEditFieldLabel                matlab.ui.control.Label
        RXEditField                     matlab.ui.control.NumericEditField
        ValordelasresistenciasalatemperaturafinalLabel  matlab.ui.control.Label
        OhmsLabel                       matlab.ui.control.Label
        OhmsLabel_2                     matlab.ui.control.Label
        OhmsLabel_3                     matlab.ui.control.Label
        OhmsLabel_4                     matlab.ui.control.Label
        OhmsLabel_5                     matlab.ui.control.Label
        OhmsLabel_6                     matlab.ui.control.Label
        VoltsLabel_4                    matlab.ui.control.Label
        VoltsLabel_5                    matlab.ui.control.Label
        AmperesLabel_2                  matlab.ui.control.Label
        AmperesLabel_3                  matlab.ui.control.Label
        clearButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function TextAreaValueChanged(app, event)
            value = app.EditField;
            
        end

        % Callback function
        function TextAreaValueChanged2(app, event)
            value = app.EditField;
            
        end

        % Callback function
        function TextAreaValueChanged3(app, event)
            value = app.Spinner;
            
        end

        % Callback function
        function EditField_12ValueChanged(app, event)
            value = app.EditField;
            
        end

        % Callback function
        function TextAreaValueChanged4(app, event)
            value = app.TextArea.Value;
            value = app.EditField
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
       %ESTE BOTON CALCULA LAS CANTIDADES INGRESADAS PARA LLENAR LOS
       %PARAMETROS DEL TRANSFORMADOR
       
       %Calculos
            Pcc    = app.PccEditField.Value
            Vcc    = app.VccEditField.Value
            Icc    = app.IccEditField.Value
            Po     = app.PoEditField.Value
            Vx     = app.VxEditField.Value
            Iexc   = app.IexcEditField.Value
            tempp  = app.TemperaturadepruebaEditField.Value
            tempf  = app.TemperaturafinalEditField.Value
            a      = (app.VoltajedealtaEditField.Value)/(app.VoltajedebajaEditField.Value)
            Rh     = ((Pcc/Icc^2)/2)
            Rxprim = ((Pcc/Icc^2)/2)
            xprim  = (sqrt((Vcc/Icc)^2-(Rh+Rxprim)^2))/2
            Rx     = (Rxprim/a^2)
            jXx    = (xprim/a^2)
            Rm     = (Po/Iexc)-Rx
            jXm    = (sqrt(((Vx/Iexc)^2)-((Rx+Rm)^2)))-0.109
            Ix     = (app.PotenciadeltransformadorEditField.Value*1000)/app.VoltajedebajaEditField.Value
            Vx     = app.VoltajedebajaEditField.Value
            Vh     = app.VoltajedealtaEditField.Value
            jXh    = (sqrt((Vcc/Icc)^2-(Rh+Rxprim)^2))/2
            Ih     = (app.PotenciadeltransformadorEditField.Value*1000)/app.VoltajedealtaEditField.Value
            RH     = ((tempf+234.5)/(tempp+234.5))*(Rh)
            RX     = ((tempf+234.5)/(tempp+234.5))*(Rx)


       %Resultados
            app.EditField.Value    = Rx
            app.EditField_2.Value  = jXx
            app.EditField_3.Value  = Rm
            app.EditField_4.Value  = jXm
            app.EditField_5.Value  = Ix
            app.EditField_6.Value  = Vx
            app.EditField_7.Value  = Rh
            app.EditField_8.Value  = jXh
            app.EditField_9.Value  = Vh
            app.EditField_10.Value = Ih
            app.RHEditField.Value  = RH 
            app.RXEditField.Value  = RX 


        end

        % Button pushed function: autofillButton
        function autofillButtonPushed(app, event)
            %LA FUNCION DE ESTE BOTON ES SUGERIR VALORES PARA CALCULAR LOS
            %PARAMETROS DEL TRANSFORMADOR
            
            app.PotenciadeltransformadorEditField.Value = 3
            app.VoltajedealtaEditField.Value            = 220
            app.VoltajedebajaEditField.Value            = 110
            app.TemperaturadepruebaEditField.Value      = 25
            app.TemperaturafinalEditField.Value         = 60
            app.PccEditField.Value                      = 125
            app.VccEditField.Value                      = 15
            app.IccEditField.Value                      = 13.6
            app.PoEditField.Value                       = 20
            app.VxEditField.Value                       = 110
            app.IexcEditField.Value                     = 0.9

           
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            %REGRESA TODOS LOS VALORES A 0
            
            app.PotenciadeltransformadorEditField.Value = 0
            app.VoltajedealtaEditField.Value            = 0
            app.VoltajedebajaEditField.Value            = 0
            app.TemperaturadepruebaEditField.Value      = 0
            app.TemperaturafinalEditField.Value         = 0
            app.PccEditField.Value                      = 0
            app.VccEditField.Value                      = 0
            app.IccEditField.Value                      = 0
            app.PoEditField.Value                       = 0
            app.VxEditField.Value                       = 0
            app.IexcEditField.Value                     = 0
            app.EditField.Value                         = 0
            app.EditField_2.Value                       = 0
            app.EditField_3.Value                       = 0
            app.EditField_4.Value                       = 0
            app.EditField_5.Value                       = 0
            app.EditField_6.Value                       = 0
            app.EditField_7.Value                       = 0
            app.EditField_8.Value                       = 0
            app.EditField_9.Value                       = 0
            app.EditField_10.Value                      = 0
            app.RHEditField.Value                       = 0
            app.RXEditField.Value                       = 0
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TerminalesdebajaencortocircuitoLabel = uilabel(app.UIFigure);
            app.TerminalesdebajaencortocircuitoLabel.Text = 'Terminales de baja en corto circuito:';
            app.TerminalesdebajaencortocircuitoLabel_2 = uilabel(app.UIFigure);
            app.TerminalesdebajaencortocircuitoLabel_2.Text = 'Terminales de baja en corto circuito:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'pia.png';
            app.WattsLabel = uilabel(app.UIFigure);
            app.WattsLabel.Text = 'Watts';
            app.VoltsLabel = uilabel(app.UIFigure);
            app.VoltsLabel.Text = 'Volts';
            app.AmperesLabel = uilabel(app.UIFigure);
            app.AmperesLabel.Text = 'Amperes';
            app.KVALabel = uilabel(app.UIFigure);
            app.KVALabel.Text = 'KVA';
            app.VoltsLabel_2 = uilabel(app.UIFigure);
            app.VoltsLabel_2.Text = 'Volts';
            app.VoltsLabel_3 = uilabel(app.UIFigure);
            app.VoltsLabel_3.Text = 'Volts';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = '°C';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'header.png';
            app.PotenciadeltransformadorEditFieldLabel = uilabel(app.UIFigure);
            app.PotenciadeltransformadorEditFieldLabel.Text = 'Potencia del transformador:';
            app.PotenciadeltransformadorEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltajedealtaEditFieldLabel = uilabel(app.UIFigure);
            app.VoltajedealtaEditFieldLabel.Text = 'Voltaje de alta:';
            app.VoltajedealtaEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltajedebajaEditFieldLabel = uilabel(app.UIFigure);
            app.VoltajedebajaEditFieldLabel.Text = 'Voltaje de baja:';
            app.VoltajedebajaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperaturadepruebaEditFieldLabel = uilabel(app.UIFigure);
            app.TemperaturadepruebaEditFieldLabel.Text = 'Temperatura de prueba:';
            app.TemperaturadepruebaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PccEditFieldLabel = uilabel(app.UIFigure);
            app.PccEditFieldLabel.Text = 'Pcc';
            app.PccEditField = uieditfield(app.UIFigure, 'numeric');
            app.PoEditFieldLabel = uilabel(app.UIFigure);
            app.PoEditFieldLabel.Text = 'Po';
            app.PoEditField = uieditfield(app.UIFigure, 'numeric');
            app.VccEditFieldLabel = uilabel(app.UIFigure);
            app.VccEditFieldLabel.Text = 'Vcc';
            app.VccEditField = uieditfield(app.UIFigure, 'numeric');
            app.VxEditFieldLabel = uilabel(app.UIFigure);
            app.VxEditFieldLabel.Text = 'Vx';
            app.VxEditField = uieditfield(app.UIFigure, 'numeric');
            app.IccEditFieldLabel = uilabel(app.UIFigure);
            app.IccEditFieldLabel.Text = 'Icc';
            app.IccEditField = uieditfield(app.UIFigure, 'numeric');
            app.IexcEditFieldLabel = uilabel(app.UIFigure);
            app.IexcEditFieldLabel.Text = 'Iexc';
            app.IexcEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.autofillButton = uibutton(app.UIFigure, 'push');
            app.autofillButton.ButtonPushedFcn = createCallbackFcn(app, @autofillButtonPushed, true);
            app.autofillButton.Text = 'auto-fill';
            app.TemperaturafinalEditFieldLabel = uilabel(app.UIFigure);
            app.TemperaturafinalEditFieldLabel.Text = 'Temperatura final:';
            app.TemperaturafinalEditField = uieditfield(app.UIFigure, 'numeric');
            app.CLabel_2 = uilabel(app.UIFigure);
            app.CLabel_2.Text = '°C';
            app.RHEditFieldLabel = uilabel(app.UIFigure);
            app.RHEditFieldLabel.Text = 'RH';
            app.RHEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXEditFieldLabel = uilabel(app.UIFigure);
            app.RXEditFieldLabel.Text = 'RX';
            app.RXEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValordelasresistenciasalatemperaturafinalLabel = uilabel(app.UIFigure);
            app.ValordelasresistenciasalatemperaturafinalLabel.Text = 'Valor de las resistencias a la temperatura final';
            app.OhmsLabel = uilabel(app.UIFigure);
            app.OhmsLabel.Text = 'Ohms';
            app.OhmsLabel_2 = uilabel(app.UIFigure);
            app.OhmsLabel_2.Text = 'Ohms';
            app.OhmsLabel_3 = uilabel(app.UIFigure);
            app.OhmsLabel_3.Text = 'Ohms';
            app.OhmsLabel_4 = uilabel(app.UIFigure);
            app.OhmsLabel_4.Text = 'Ohms';
            app.OhmsLabel_5 = uilabel(app.UIFigure);
            app.OhmsLabel_5.Text = 'Ohms';
            app.OhmsLabel_6 = uilabel(app.UIFigure);
            app.OhmsLabel_6.Text = 'Ohms';
            app.VoltsLabel_4 = uilabel(app.UIFigure);
            app.VoltsLabel_4.Text = 'Volts';
            app.VoltsLabel_5 = uilabel(app.UIFigure);
            app.VoltsLabel_5.Text = 'Volts';
            app.AmperesLabel_2 = uilabel(app.UIFigure);
            app.AmperesLabel_2.Text = 'Amperes';
            app.AmperesLabel_3 = uilabel(app.UIFigure);
            app.AmperesLabel_3.Text = 'Amperes';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end